classdef NP2ImroGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SaveSelectedChsButton      matlab.ui.control.Button
        ImportSelectedChsButton    matlab.ui.control.Button
        SelectedChsTextArea        matlab.ui.control.TextArea
        SelectedChsTextAreaLabel   matlab.ui.control.Label
        ChannelsLeftLabel          matlab.ui.control.Label
        EditField_4                matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.NumericEditField
        EditField                  matlab.ui.control.NumericEditField
        SelectChannelsButton       matlab.ui.control.StateButton
        CreateImroButton           matlab.ui.control.StateButton
        LoadProbeViewButton        matlab.ui.control.StateButton
        NP2FourShankProbeImroChannelMapGeneratorGUILabel  matlab.ui.control.Label
        Sh4TotalChsEditField       matlab.ui.control.NumericEditField
        Sh4TotalChsEditFieldLabel  matlab.ui.control.Label
        Sh3TotalChsEditField       matlab.ui.control.NumericEditField
        Sh3TotalChsEditFieldLabel  matlab.ui.control.Label
        Sh2TotalChsEditField       matlab.ui.control.NumericEditField
        Sh2TotalChsEditFieldLabel  matlab.ui.control.Label
        Sh1TotalChsEditField       matlab.ui.control.NumericEditField
        Sh1TotalChsEditFieldLabel  matlab.ui.control.Label
        BankListBox                matlab.ui.control.ListBox
        BankListBoxLabel           matlab.ui.control.Label
        ShankListBox               matlab.ui.control.ListBox
        ShankListBoxLabel          matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Channels_Selected = 0;% add here the value of editing fields across select channel clicks
        Channels_Selected2 = 0;
        Channels_Selected3 = 0;
        Channels_Selected4 = 0;
        AllChannels = [];
        SelChannels;
        SelChannels_num;
        roi;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Sh1TotalChsEditField
        function Sh1TotalChsEditFieldValueChanged(app, event)
            if app.Sh1TotalChsEditField.Value < 385
                value = app.Sh1TotalChsEditField.Value;
                app.AllChannels = app.Channels_Selected + app.Channels_Selected2 + app.Channels_Selected3 + app.Channels_Selected4;
                
                if app.AllChannels < 385 && app.AllChannels > -1 
                    app.Channels_Selected = value;
                    app.EditFieldValueChanged
                else
                    app.Channels_Selected = 0;
                    app.EditFieldValueChanged
                    errordlg("You can only select 384 channels to create an IMRO file.","Channel Number Error");
                end
            else
                errordlg("You can only record from 384 channels.","Channel Number Error");
            end
        end

        % Value changed function: Sh2TotalChsEditField
        function Sh2TotalChsEditFieldValueChanged(app, event)
            if app.Sh2TotalChsEditField.Value < 385
                value = app.Sh2TotalChsEditField.Value;
                app.AllChannels = app.Channels_Selected + app.Channels_Selected2 + app.Channels_Selected3 + app.Channels_Selected4;
                
                if app.AllChannels < 385 && app.AllChannels > -1 
                    app.Channels_Selected2 = value;
                    app.EditField_2ValueChanged
                else
                    app.Channels_Selected2 = 0;
                    app.EditField_2ValueChanged
                    errordlg("You can only select 384 channels to create an IMRO file.","Channel Number Error");
                end
            else
                errordlg("You can only record from 384 channels.","Channel Number Error");
            end
        end

        % Value changed function: Sh3TotalChsEditField
        function Sh3TotalChsEditFieldValueChanged(app, event)
            if app.Sh3TotalChsEditField.Value < 385
                value = app.Sh3TotalChsEditField.Value;
                app.AllChannels = app.Channels_Selected + app.Channels_Selected2 + app.Channels_Selected3 + app.Channels_Selected4;
                
                if app.AllChannels < 385 && app.AllChannels > -1 
                    app.Channels_Selected3 = value;
                    app.EditField_3ValueChanged
                else
                    app.Channels_Selected3 = 0;
                    app.EditField_3ValueChanged
                    errordlg("You can only select 384 channels to create an IMRO file.","Channel Number Error");
                end
            else
                errordlg("You can only record from 384 channels.","Channel Number Error");
            end
        end

        % Value changed function: Sh4TotalChsEditField
        function Sh4TotalChsEditFieldValueChanged(app, event)
            if app.Sh4TotalChsEditField.Value < 385
                value = app.Sh4TotalChsEditField.Value;
                app.AllChannels = app.Channels_Selected + app.Channels_Selected2 + app.Channels_Selected3 + app.Channels_Selected4;
                
                if app.AllChannels < 385 && app.AllChannels > -1 
                    app.Channels_Selected4 = value;
                    app.EditField_4ValueChanged
                else
                    app.Channels_Selected4 = 0;
                    app.EditField_4ValueChanged
                    errordlg("You can only select 384 channels to create an IMRO file.","Channel Number Error");
                end
            else
                errordlg("You can only record from 384 channels.","Channel Number Error");
            end
        end

        % Value changed function: BankListBox
        function BankListBoxValueChanged(app, event)
            value = app.BankListBox.Value;
        end

        % Value changed function: ShankListBox
        function ShankListBoxValueChanged(app, event)
            value = app.ShankListBox.Value;
        end

        % Value changed function: LoadProbeViewButton
        function LoadProbeViewButtonValueChanged(app, event)
            value = app.LoadProbeViewButton.Value;
            %here is where you integrate the information to plot the probe view!
            %this is basically like Shahin's excel doc
            %there is no imro generation at this step
            
            %this is the bank view
            totsitessh1 = zeros(1280,2,'single');
            totsitessh2 = zeros(1280,2,'single');
            totsitessh3 = zeros(1280,2,'single');
            totsitessh4 = zeros(1280,2,'single');
                
            totsitessh1(:,1) = [sort(1:640,'descend') sort(1:640,'descend')];
            totsitessh1(1:640,2) = 1;
            totsitessh1(641:1280,2) = 1.1;
            
            totsitessh2(:,1) = [sort(1:640,'descend') sort(1:640,'descend')];
            totsitessh2(1:640,2) = 2;
            totsitessh2(641:1280,2) = 2.1;
            
            totsitessh3(:,1) = [sort(1:640,'descend') sort(1:640,'descend')];
            totsitessh3(1:640,2) = 3;
            totsitessh3(641:1280,2) = 3.1;
            
            totsitessh4(:,1) = [sort(1:640,'descend') sort(1:640,'descend')];
            totsitessh4(1:640,2) = 4;
            totsitessh4(641:1280,2) = 4.1;      
            
            %this is the blocks for each selected shank and bank
            blocks = [4,3,8,7;2,1,6,5;8,7,4,3;6,5,2,1;7,8,3,4;5,6,1,2;3,4,7,8;1,2,5,6];
               
            blockscolor1 = [0.4660, 0.6740, 0.1880];
            blockscolor2 = [0, 0.5, 0];
            blockscolor3 = [0.75, 0, 0.75];
            blockscolor4 = [0.4940, 0.1840, 0.5560];
            blockscolor5 = [0.75, 0.75, 0];
            blockscolor6 = [1, 0, 0];
            blockscolor7 = [0.3010, 0.7450, 0.9330];
            blockscolor8 = [0, 0, 1];
                        
            
            if app.ShankListBox.Value == "All Shanks (Just for Visualization)"
                app.UIAxes.XLim = [0 5];
                
                if app.BankListBox.Value == "Bank 0 (bottom)"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh1(:,2),totsitessh1(:,1),'black')
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2(:,2),totsitessh2(:,1),'black')
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3(:,2),totsitessh3(:,1),'black')
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh4(:,2),totsitessh4(:,1),'black')
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh1([617:640 1257:1280],2),totsitessh1([617:640 1257:1280],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([593:616 1233:1256],2),totsitessh1([593:616 1233:1256],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([569:592 1209:1232],2),totsitessh1([569:592 1209:1232],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([545:568 1185:1208],2),totsitessh1([545:568 1185:1208],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([521:544 1161:1184],2),totsitessh1([521:544 1161:1184],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([497:520 1137:1160],2),totsitessh1([497:520 1137:1160],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([471:496 1113:1136],2),totsitessh1([471:496 1113:1136],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([449:472 1089:1112],2),totsitessh1([449:472 1089:1112],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh2([617:640 1257:1280],2),totsitessh2([617:640 1257:1280],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([593:616 1233:1256],2),totsitessh2([593:616 1233:1256],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([569:592 1209:1232],2),totsitessh2([569:592 1209:1232],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([545:568 1185:1208],2),totsitessh2([545:568 1185:1208],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([521:544 1161:1184],2),totsitessh2([521:544 1161:1184],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([497:520 1137:1160],2),totsitessh2([497:520 1137:1160],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([471:496 1113:1136],2),totsitessh2([471:496 1113:1136],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([449:472 1089:1112],2),totsitessh2([449:472 1089:1112],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh3([617:640 1257:1280],2),totsitessh3([617:640 1257:1280],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([593:616 1233:1256],2),totsitessh3([593:616 1233:1256],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([569:592 1209:1232],2),totsitessh3([569:592 1209:1232],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([545:568 1185:1208],2),totsitessh3([545:568 1185:1208],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([521:544 1161:1184],2),totsitessh3([521:544 1161:1184],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([497:520 1137:1160],2),totsitessh3([497:520 1137:1160],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([471:496 1113:1136],2),totsitessh3([471:496 1113:1136],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([449:472 1089:1112],2),totsitessh3([449:472 1089:1112],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh4([617:640 1257:1280],2),totsitessh4([617:640 1257:1280],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([593:616 1233:1256],2),totsitessh4([593:616 1233:1256],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([569:592 1209:1232],2),totsitessh4([569:592 1209:1232],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh4([545:568 1185:1208],2),totsitessh4([545:568 1185:1208],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([521:544 1161:1184],2),totsitessh4([521:544 1161:1184],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([497:520 1137:1160],2),totsitessh4([497:520 1137:1160],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([471:496 1113:1136],2),totsitessh4([471:496 1113:1136],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([449:472 1089:1112],2),totsitessh4([449:472 1089:1112],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    
                elseif app.BankListBox.Value == "Bank 1"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh1(:,2),totsitessh1(:,1),'black')
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2(:,2),totsitessh2(:,1),'black')
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3(:,2),totsitessh3(:,1),'black')
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh4(:,2),totsitessh4(:,1),'black')
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh1([425:448 1065:1088],2),totsitessh1([425:448 1065:1088],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([401:424 1041:1064],2),totsitessh1([401:424 1041:1064],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([377:400 1017:1040],2),totsitessh1([377:400 1017:1040],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([353:376 993:1016],2),totsitessh1([353:376 993:1016],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([329:352 969:992],2),totsitessh1([329:352 969:992],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([305:328 945:968],2),totsitessh1([305:328 945:968],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([281:304 921:944],2),totsitessh1([281:304 921:944],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([257:280 897:920],2),totsitessh1([257:280 897:920],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh2([425:448 1065:1088],2),totsitessh2([425:448 1065:1088],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([401:424 1041:1064],2),totsitessh2([401:424 1041:1064],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([377:400 1017:1040],2),totsitessh2([377:400 1017:1040],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([353:376 993:1016],2),totsitessh2([353:376 993:1016],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([329:352 969:992],2),totsitessh2([329:352 969:992],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([305:328 945:968],2),totsitessh2([305:328 945:968],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([281:304 921:944],2),totsitessh2([281:304 921:944],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([257:280 897:920],2),totsitessh2([257:280 897:920],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh3([425:448 1065:1088],2),totsitessh3([425:448 1065:1088],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([401:424 1041:1064],2),totsitessh3([401:424 1041:1064],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([377:400 1017:1040],2),totsitessh3([377:400 1017:1040],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([353:376 993:1016],2),totsitessh3([353:376 993:1016],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([329:352 969:992],2),totsitessh3([329:352 969:992],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([305:328 945:968],2),totsitessh3([305:328 945:968],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([281:304 921:944],2),totsitessh3([281:304 921:944],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([257:280 897:920],2),totsitessh3([257:280 897:920],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh4([425:448 1065:1088],2),totsitessh4([425:448 1065:1088],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([401:424 1041:1064],2),totsitessh4([401:424 1041:1064],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([377:400 1017:1040],2),totsitessh4([377:400 1017:1040],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh4([353:376 993:1016],2),totsitessh4([353:376 993:1016],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([329:352 969:992],2),totsitessh4([329:352 969:992],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([305:328 945:968],2),totsitessh4([305:328 945:968],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([281:304 921:944],2),totsitessh4([281:304 921:944],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([257:280 897:920],2),totsitessh4([257:280 897:920],1),'MarkerEdgeColor',blockscolor7)
                    
                elseif app.BankListBox.Value == "Bank 2" 
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh1(:,2),totsitessh1(:,1),'black')
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2(:,2),totsitessh2(:,1),'black')
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3(:,2),totsitessh3(:,1),'black')
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh4(:,2),totsitessh4(:,1),'black')
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh1([233:256 873:896],2),totsitessh1([233:256 873:896],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([209:232 849:872],2),totsitessh1([209:232 849:872],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([185:208 825:848],2),totsitessh1([185:208 825:848],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([161:184 801:824],2),totsitessh1([161:184 801:824],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([137:160 777:800],2),totsitessh1([137:160 777:800],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([113:136 753:776],2),totsitessh1([113:136 753:776],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([89:112 729:752],2),totsitessh1([89:112 729:752],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([65:88 705:728],2),totsitessh1([65:88 705:728],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh2([233:256 873:896],2),totsitessh2([233:256 873:896],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([209:232 849:872],2),totsitessh2([209:232 849:872],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([185:208 825:848],2),totsitessh2([185:208 825:848],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([161:184 801:824],2),totsitessh2([161:184 801:824],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([137:160 777:800],2),totsitessh2([137:160 777:800],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([113:136 753:776],2),totsitessh2([113:136 753:776],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([89:112 729:752],2),totsitessh2([89:112 729:752],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([65:88 705:728],2),totsitessh2([65:88 705:728],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh3([233:256 873:896],2),totsitessh3([233:256 873:896],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([209:232 849:872],2),totsitessh3([209:232 849:872],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([185:208 825:848],2),totsitessh3([185:208 825:848],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([161:184 801:824],2),totsitessh3([161:184 801:824],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([137:160 777:800],2),totsitessh3([137:160 777:800],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([113:136 753:776],2),totsitessh3([113:136 753:776],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([89:112 729:752],2),totsitessh3([89:112 729:752],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([65:88 705:728],2),totsitessh3([65:88 705:728],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh4([233:256 873:896],2),totsitessh4([233:256 873:896],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([209:232 849:872],2),totsitessh4([209:232 849:872],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([185:208 825:848],2),totsitessh4([185:208 825:848],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh4([161:184 801:824],2),totsitessh4([161:184 801:824],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([137:160 777:800],2),totsitessh4([137:160 777:800],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([113:136 753:776],2),totsitessh4([113:136 753:776],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([89:112 729:752],2),totsitessh4([89:112 729:752],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([65:88 705:728],2),totsitessh4([65:88 705:728],1),'MarkerEdgeColor',blockscolor7)
                    
                elseif app.BankListBox.Value == "Bank 3 (top)" 
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh1(:,2),totsitessh1(:,1),'black')
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2(:,2),totsitessh2(:,1),'black')
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3(:,2),totsitessh3(:,1),'black')
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh4(:,2),totsitessh4(:,1),'black')
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh1([41:64 681:704],2),totsitessh1([41:64 681:704],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([17:40 657:680],2),totsitessh1([17:40 657:680],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([1:16 641:656],2),totsitessh1([1:16 641:656],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on') 
                    
                    scatter(app.UIAxes,totsitessh2([41:64 681:704],2),totsitessh2([41:64 681:704],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([17:40 657:680],2),totsitessh2([17:40 657:680],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([1:16 641:656],2),totsitessh2([1:16 640:656],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    
                    scatter(app.UIAxes,totsitessh3([41:64 681:704],2),totsitessh3([41:64 681:704],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([17:40 657:680],2),totsitessh3([17:40 657:680],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([1:16 641:656],2),totsitessh3([1:16 641:656],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    
                    scatter(app.UIAxes,totsitessh4([41:64 681:704],2),totsitessh4([41:64 681:704],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([17:40 657:680],2),totsitessh4([17:40 657:680],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([1:16 641:656],2),totsitessh4([1:16 641:656],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    
                elseif app.BankListBox.Value == "All Banks (Just for Visualization)"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh1(:,2),totsitessh1(:,1))
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2(:,2),totsitessh2(:,1))
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3(:,2),totsitessh3(:,1))
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4(:,2),totsitessh4(:,1))
                    hold(app.UIAxes, 'on')
                    
                    %bank 0
                    scatter(app.UIAxes,totsitessh1([617:640 1257:1280],2),totsitessh1([617:640 1257:1280],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([593:616 1233:1256],2),totsitessh1([593:616 1233:1256],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([569:592 1209:1232],2),totsitessh1([569:592 1209:1232],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([545:568 1185:1208],2),totsitessh1([545:568 1185:1208],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([521:544 1161:1184],2),totsitessh1([521:544 1161:1184],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([497:520 1137:1160],2),totsitessh1([497:520 1137:1160],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([471:496 1113:1136],2),totsitessh1([471:496 1113:1136],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([449:472 1089:1112],2),totsitessh1([449:472 1089:1112],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh2([617:640 1257:1280],2),totsitessh2([617:640 1257:1280],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([593:616 1233:1256],2),totsitessh2([593:616 1233:1256],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([569:592 1209:1232],2),totsitessh2([569:592 1209:1232],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([545:568 1185:1208],2),totsitessh2([545:568 1185:1208],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([521:544 1161:1184],2),totsitessh2([521:544 1161:1184],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([497:520 1137:1160],2),totsitessh2([497:520 1137:1160],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([471:496 1113:1136],2),totsitessh2([471:496 1113:1136],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([449:472 1089:1112],2),totsitessh2([449:472 1089:1112],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh3([617:640 1257:1280],2),totsitessh3([617:640 1257:1280],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([593:616 1233:1256],2),totsitessh3([593:616 1233:1256],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([569:592 1209:1232],2),totsitessh3([569:592 1209:1232],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([545:568 1185:1208],2),totsitessh3([545:568 1185:1208],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([521:544 1161:1184],2),totsitessh3([521:544 1161:1184],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([497:520 1137:1160],2),totsitessh3([497:520 1137:1160],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([471:496 1113:1136],2),totsitessh3([471:496 1113:1136],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([449:472 1089:1112],2),totsitessh3([449:472 1089:1112],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh4([617:640 1257:1280],2),totsitessh4([617:640 1257:1280],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([593:616 1233:1256],2),totsitessh4([593:616 1233:1256],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([569:592 1209:1232],2),totsitessh4([569:592 1209:1232],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh4([545:568 1185:1208],2),totsitessh4([545:568 1185:1208],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([521:544 1161:1184],2),totsitessh4([521:544 1161:1184],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([497:520 1137:1160],2),totsitessh4([497:520 1137:1160],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([471:496 1113:1136],2),totsitessh4([471:496 1113:1136],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([449:472 1089:1112],2),totsitessh4([449:472 1089:1112],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    
                    %bank 1
                    scatter(app.UIAxes,totsitessh1([425:448 1065:1088],2),totsitessh1([425:448 1065:1088],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([401:424 1041:1064],2),totsitessh1([401:424 1041:1064],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([377:400 1017:1040],2),totsitessh1([377:400 1017:1040],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([353:376 993:1016],2),totsitessh1([353:376 993:1016],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([329:352 969:992],2),totsitessh1([329:352 969:992],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([305:328 945:968],2),totsitessh1([305:328 945:968],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([281:304 921:944],2),totsitessh1([281:304 921:944],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([257:280 897:920],2),totsitessh1([257:280 897:920],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh2([425:448 1065:1088],2),totsitessh2([425:448 1065:1088],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([401:424 1041:1064],2),totsitessh2([401:424 1041:1064],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([377:400 1017:1040],2),totsitessh2([377:400 1017:1040],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([353:376 993:1016],2),totsitessh2([353:376 993:1016],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([329:352 969:992],2),totsitessh2([329:352 969:992],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([305:328 945:968],2),totsitessh2([305:328 945:968],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([281:304 921:944],2),totsitessh2([281:304 921:944],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([257:280 897:920],2),totsitessh2([257:280 897:920],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh3([425:448 1065:1088],2),totsitessh3([425:448 1065:1088],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([401:424 1041:1064],2),totsitessh3([401:424 1041:1064],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([377:400 1017:1040],2),totsitessh3([377:400 1017:1040],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([353:376 993:1016],2),totsitessh3([353:376 993:1016],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([329:352 969:992],2),totsitessh3([329:352 969:992],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([305:328 945:968],2),totsitessh3([305:328 945:968],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([281:304 921:944],2),totsitessh3([281:304 921:944],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([257:280 897:920],2),totsitessh3([257:280 897:920],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh4([425:448 1065:1088],2),totsitessh4([425:448 1065:1088],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([401:424 1041:1064],2),totsitessh4([401:424 1041:1064],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([377:400 1017:1040],2),totsitessh4([377:400 1017:1040],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh4([353:376 993:1016],2),totsitessh4([353:376 993:1016],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([329:352 969:992],2),totsitessh4([329:352 969:992],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([305:328 945:968],2),totsitessh4([305:328 945:968],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([281:304 921:944],2),totsitessh4([281:304 921:944],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([257:280 897:920],2),totsitessh4([257:280 897:920],1),'MarkerEdgeColor',blockscolor7)
                    
                    %bank 2
                    scatter(app.UIAxes,totsitessh1([233:256 873:896],2),totsitessh1([233:256 873:896],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([209:232 849:872],2),totsitessh1([209:232 849:872],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([185:208 825:848],2),totsitessh1([185:208 825:848],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([161:184 801:824],2),totsitessh1([161:184 801:824],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([137:160 777:800],2),totsitessh1([137:160 777:800],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([113:136 753:776],2),totsitessh1([113:136 753:776],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([89:112 729:752],2),totsitessh1([89:112 729:752],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([65:88 705:728],2),totsitessh1([65:88 705:728],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh2([233:256 873:896],2),totsitessh2([233:256 873:896],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([209:232 849:872],2),totsitessh2([209:232 849:872],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([185:208 825:848],2),totsitessh2([185:208 825:848],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([161:184 801:824],2),totsitessh2([161:184 801:824],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([137:160 777:800],2),totsitessh2([137:160 777:800],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([113:136 753:776],2),totsitessh2([113:136 753:776],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([89:112 729:752],2),totsitessh2([89:112 729:752],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([65:88 705:728],2),totsitessh2([65:88 705:728],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh3([233:256 873:896],2),totsitessh3([233:256 873:896],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([209:232 849:872],2),totsitessh3([209:232 849:872],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([185:208 825:848],2),totsitessh3([185:208 825:848],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([161:184 801:824],2),totsitessh3([161:184 801:824],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([137:160 777:800],2),totsitessh3([137:160 777:800],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([113:136 753:776],2),totsitessh3([113:136 753:776],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([89:112 729:752],2),totsitessh3([89:112 729:752],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([65:88 705:728],2),totsitessh3([65:88 705:728],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    
                    scatter(app.UIAxes,totsitessh4([233:256 873:896],2),totsitessh4([233:256 873:896],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([209:232 849:872],2),totsitessh4([209:232 849:872],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([185:208 825:848],2),totsitessh4([185:208 825:848],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh4([161:184 801:824],2),totsitessh4([161:184 801:824],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([137:160 777:800],2),totsitessh4([137:160 777:800],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([113:136 753:776],2),totsitessh4([113:136 753:776],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([89:112 729:752],2),totsitessh4([89:112 729:752],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([65:88 705:728],2),totsitessh4([65:88 705:728],1),'MarkerEdgeColor',blockscolor7)
                    
                    %bank 3
                    scatter(app.UIAxes,totsitessh1([41:64 681:704],2),totsitessh1([41:64 681:704],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([17:40 657:680],2),totsitessh1([17:40 657:680],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([1:16 641:656],2),totsitessh1([1:16 641:656],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on') 
                    
                    scatter(app.UIAxes,totsitessh2([41:64 681:704],2),totsitessh2([41:64 681:704],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([17:40 657:680],2),totsitessh2([17:40 657:680],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([1:16 641:656],2),totsitessh2([1:16 641:656],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    
                    scatter(app.UIAxes,totsitessh3([41:64 681:704],2),totsitessh3([41:64 681:704],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([17:40 657:680],2),totsitessh3([17:40 657:680],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([1:16 641:656],2),totsitessh3([1:16 641:656],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    
                    scatter(app.UIAxes,totsitessh4([41:64 681:704],2),totsitessh4([41:64 681:704],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([17:40 657:680],2),totsitessh4([17:40 657:680],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([1:16 641:656],2),totsitessh4([1:16 641:656],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    
                else
                    errordlg("Cannot load specified Bank Number.","Bank Number Error");
                end
                
            elseif app.ShankListBox.Value == "Shank 1"
                if app.BankListBox.Value == "Bank 0 (bottom)"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh1(:,2),totsitessh1(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([617:640 1257:1280],2),totsitessh1([617:640 1257:1280],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([593:616 1233:1256],2),totsitessh1([593:616 1233:1256],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([569:592 1209:1232],2),totsitessh1([569:592 1209:1232],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([545:568 1185:1208],2),totsitessh1([545:568 1185:1208],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([521:544 1161:1184],2),totsitessh1([521:544 1161:1184],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([497:520 1137:1160],2),totsitessh1([497:520 1137:1160],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([471:496 1113:1136],2),totsitessh1([471:496 1113:1136],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh1([449:472 1089:1112],2),totsitessh1([449:472 1089:1112],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                elseif app.BankListBox.Value == "Bank 1"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh1(:,2),totsitessh1(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([257:448 897:1088],2),totsitessh1([257:448 897:1088],1),'MarkerEdgeColor',[0, 0.4470, 0.7410]);
                elseif app.BankListBox.Value == "Bank 2" 
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh1(:,2),totsitessh1(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([65:256 705:896],2),totsitessh1([65:256 705:896],1),'MarkerEdgeColor',[0, 0.4470, 0.7410]);
                elseif app.BankListBox.Value == "Bank 3 (top)" 
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh1(:,2),totsitessh1(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh1([1:64 640:704],2),totsitessh1([1:64 640:704],1),'MarkerEdgeColor',[0, 0.4470, 0.7410]);
                elseif app.BankListBox.Value == "All Banks (Just for Visualization)"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh1(:,2),totsitessh1(:,1),'MarkerEdgeColor',[0, 0.4470, 0.7410]);
                else
                    errordlg("Cannot load specified Bank Number.","Bank Number Error");
                end
                
            elseif app.ShankListBox.Value == "Shank 2"
                 if app.BankListBox.Value == "Bank 0 (bottom)"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh2(:,2),totsitessh2(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([617:640 1257:1280],2),totsitessh2([617:640 1257:1280],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([593:616 1233:1256],2),totsitessh2([593:616 1233:1256],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([569:592 1209:1232],2),totsitessh2([569:592 1209:1232],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([545:568 1185:1208],2),totsitessh2([545:568 1185:1208],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([521:544 1161:1184],2),totsitessh2([521:544 1161:1184],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([497:520 1137:1160],2),totsitessh2([497:520 1137:1160],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([471:496 1113:1136],2),totsitessh2([471:496 1113:1136],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh2([449:472 1089:1112],2),totsitessh2([449:472 1089:1112],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                elseif app.BankListBox.Value == "Bank 1"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh2(:,2),totsitessh2(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([257:448 897:1088],2),totsitessh2([257:448 897:1088],1),'MarkerEdgeColor',[0.8500, 0.3250, 0.0980]);
                elseif app.BankListBox.Value == "Bank 2" 
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh2(:,2),totsitessh2(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([65:256 705:896],2),totsitessh2([65:256 705:896],1),'MarkerEdgeColor',[0.8500, 0.3250, 0.0980]);
                elseif app.BankListBox.Value == "Bank 3 (top)" 
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh2(:,2),totsitessh2(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh2([1:64 640:704],2),totsitessh2([1:64 640:704],1),'MarkerEdgeColor',[0.8500, 0.3250, 0.0980]);
                elseif app.BankListBox.Value == "All Banks (Just for Visualization)"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh2(:,2),totsitessh2(:,1),'MarkerEdgeColor',[0.8500, 0.3250, 0.0980]);
                else
                    errordlg("Cannot load specified Bank Number.","Bank Number Error");
                end
                
            elseif app.ShankListBox.Value == "Shank 3"
                 if app.BankListBox.Value == "Bank 0 (bottom)"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh3(:,2),totsitessh3(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([617:640 1257:1280],2),totsitessh3([617:640 1257:1280],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([593:616 1233:1256],2),totsitessh3([593:616 1233:1256],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([569:592 1209:1232],2),totsitessh3([569:592 1209:1232],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([545:568 1185:1208],2),totsitessh3([545:568 1185:1208],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([521:544 1161:1184],2),totsitessh3([521:544 1161:1184],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([497:520 1137:1160],2),totsitessh3([497:520 1137:1160],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([471:496 1113:1136],2),totsitessh3([471:496 1113:1136],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh3([449:472 1089:1112],2),totsitessh3([449:472 1089:1112],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                elseif app.BankListBox.Value == "Bank 1"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh3(:,2),totsitessh3(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([257:448 897:1088],2),totsitessh3([257:448 897:1088],1),'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                elseif app.BankListBox.Value == "Bank 2" 
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh3(:,2),totsitessh3(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([65:256 705:896],2),totsitessh3([65:256 705:896],1),'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                elseif app.BankListBox.Value == "Bank 3 (top)" 
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh3(:,2),totsitessh3(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh3([1:64 640:704],2),totsitessh3([1:64 640:704],1),'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                elseif app.BankListBox.Value == "All Banks (Just for Visualization)"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh3(:,2),totsitessh3(:,1),'MarkerEdgeColor',[0.9290, 0.6940, 0.1250])
                else
                    errordlg("Cannot load specified Bank Number.","Bank Number Error");
                end
                
            elseif app.ShankListBox.Value == "Shank 4"
                 if app.BankListBox.Value == "Bank 0 (bottom)"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh4(:,2),totsitessh4(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([617:640 1257:1280],2),totsitessh4([617:640 1257:1280],1),'MarkerEdgeColor',blockscolor6)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([593:616 1233:1256],2),totsitessh4([593:616 1233:1256],1),'MarkerEdgeColor',blockscolor8)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([569:592 1209:1232],2),totsitessh4([569:592 1209:1232],1),'MarkerEdgeColor',blockscolor2)
                    hold(app.UIAxes, 'on') 
                    scatter(app.UIAxes,totsitessh4([545:568 1185:1208],2),totsitessh4([545:568 1185:1208],1),'MarkerEdgeColor',blockscolor4)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([521:544 1161:1184],2),totsitessh4([521:544 1161:1184],1),'MarkerEdgeColor',blockscolor1)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([497:520 1137:1160],2),totsitessh4([497:520 1137:1160],1),'MarkerEdgeColor',blockscolor3)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([471:496 1113:1136],2),totsitessh4([471:496 1113:1136],1),'MarkerEdgeColor',blockscolor5)
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([449:472 1089:1112],2),totsitessh4([449:472 1089:1112],1),'MarkerEdgeColor',blockscolor7)
                    hold(app.UIAxes, 'on')
                elseif app.BankListBox.Value == "Bank 1"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh4(:,2),totsitessh4(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([257:448 897:1088],2),totsitessh4([257:448 897:1088],1),'MarkerEdgeColor',[0.4940, 0.1840, 0.5560]);
                elseif app.BankListBox.Value == "Bank 2" 
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh4(:,2),totsitessh4(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([65:256 705:896],2),totsitessh4([65:256 705:896],1),'MarkerEdgeColor',[0.4940, 0.1840, 0.5560]);
                elseif app.BankListBox.Value == "Bank 3 (top)" 
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh4(:,2),totsitessh4(:,1),'black');
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,totsitessh4([1:64 640:704],2),totsitessh4([1:64 640:704],1),'MarkerEdgeColor',[0.4940, 0.1840, 0.5560]);
                elseif app.BankListBox.Value == "All Banks (Just for Visualization)"
                    cla(app.UIAxes);
                    scatter(app.UIAxes,totsitessh4(:,2),totsitessh4(:,1),'MarkerEdgeColor',[0.4940, 0.1840, 0.5560])
                else
                    errordlg("Cannot load specified Bank Number.","Bank Number Error");
                end
                
            else
                errordlg("Cannot load specified Shank Number.","Shank Number Error");
            end
        
            
        end

        % Value changed function: SelectChannelsButton
        function SelectChannelsButtonValueChanged(app, event)
            value = app.SelectChannelsButton.Value;
            app.ShankListBox.Value = "All Shanks (Just for Visualization)";
            LoadProbeViewButtonValueChanged(app, event)
            app.SelChannels = cell(1,384);
            
%             for i = 1:384
%             datacursormode(app.UIFigure, 'on')
%             fhv = app.UIFigure.HandleVisibility;        % Current status
%             app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
%             set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
%             
%             [x,y,b] = ginput(1);                        % run ginput
%             app.UIFigure.HandleVisibility = fhv;        % return original state
%           
%             app.AllChannels = [string(num2str(x) + ", " + num2str(y))];
%             app.SelChannels{i} = {app.AllChannels};
%             app.SelectedChsTextAreaValueChanged
%             end
              brush('on');
     
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.EditField.Value = app.Channels_Selected;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.EditField_2.Value = app.Channels_Selected2;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.EditField_3.Value = app.Channels_Selected3;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.EditField_4.Value = app.Channels_Selected4;
        end

        % Callback function
        function SelectedChsListBoxValueChanged(app, event)
            value = app.SelectedChsListBox.Value;
        end

        % Value changed function: SelectedChsTextArea
        function SelectedChsTextAreaValueChanged(app, event)
            value = app.SelectedChsTextArea.Value;
            app.SelectedChsTextArea.Value = app.SelChannels;
        end

        % Button pushed function: ImportSelectedChsButton
        function ImportSelectedChsButtonPushed(app, event)
            name=input('Type the name of your brushed selected chs var.','s');
            if isempty(app.SelChannels)
                app.SelChannels = evalin('base',name);
                app.SelChannels_num = evalin('base',name);
                app.SelChannels = app.SelChannels(:,1) + " " + app.SelChannels(:,2);
            else
                oldChannels = app.SelChannels;
                oldChannels_num = app.SelChannels_num;
                app.SelChannels = evalin('base',name);
                app.SelChannels_num = evalin('base',name);
                app.SelChannels = app.SelChannels(:,1) + " " + app.SelChannels(:,2);
                app.SelChannels_num = [oldChannels_num; app.SelChannels_num];
                app.SelChannels = [oldChannels; app.SelChannels];
            end
            SelectedChsTextAreaValueChanged(app,event)
        end

        % Value changed function: CreateImroButton
        function CreateImroButtonValueChanged(app, event)
            value = app.CreateImroButton.Value;
            if isempty(app.SelChannels)
                errordlg("There are no selected channels.","Imro Generation Error");
            else
                chans(:,1) = 0:383; % 384 channels
                bMapOK = 1;
                
                refElec=input('Pick a reference electrode (0 for external, 1-4 for tip reference on shank 0-3).');
                
                shank = zeros(384,1,'single');
                bank = zeros(384,1,'single');
                elecInd = zeros(384,1,'single');
                chans = zeros(384,1,'single');
                
                
%                 if length(app.SelChannels) <= 384
                    SelectedChsNum = evalin('base','data_selchs');
                    
                    %load channels from selChannels
                    for i = 1:length(SelectedChsNum)
                        if rem(SelectedChsNum(i,1),1) == 0
                            shank(i) = round(SelectedChsNum(i,1));
                            elecInd(i) = SelectedChsNum(i,2) - 1;
                        else
                            shank(i) = round(SelectedChsNum(i,1));
                            elecInd(i) = SelectedChsNum(i,2) - 1;
                        end
                    end
                               
                    for i = 1:numel(elecInd)
                        %electrode to channel map
                        elecMap = zeros(4,8,'single');
                        elecMap(1,:) = [0,2,4,6,5,7,1,3];
                        elecMap(2,:) = [1,3,5,7,4,6,0,2];
                        elecMap(3,:) = [4,6,0,2,1,3,5,7];
                        elecMap(4,:) = [5,7,1,3,0,2,4,6];
                        
                        bank(i) = fix(elecInd(i)/384);
                        
                        %which block within the bank?
                        blockID = fix((elecInd(i) - bank(i)*384)/48);
                        
                        %which channel within the block?
                        subBlockInd = mod((elecInd(i) - bank(i)*384), 48);
                        
                        %get the channel number for that shank, bank, and block combo
                        chans(i) = 48*elecMap(shank(i)+1, blockID+1) + subBlockInd; 
                    end
                    
%                 else
%                     errordlg("More than 384 channels are selected.","Imro Generation Error");
%                 end
                
                
                for i = 1:384
                    if sum( chans(1:i-1)==chans(i) ) > 0
                    fprintf( "duplicate channels => impossible map\n" );
                    bMapOK = 0;
                    end
                end

                if bMapOK
                    %open a new file wherever we are
                    nameStr=input('Name IMRO File.','s');
                    fileName = [nameStr '.imro'];
                    %uipathname = uigetdir('C:\NP2ImroGui\');
                    nmID = fopen(['C:\NP2ImroGui\' fileName],'w');
                
                    [chans,sortI] = sort(chans);
                    bank = int32(bank(sortI));
                    shank = int32(shank(sortI));
                    elecInd = elecInd(sortI);
                
                    % imro table
                    % print first entry, specifying probe type and number of channels
                    fprintf(nmID,'(%d,%d)', 24, 384);
                    for i = 1:numel(chans)
                        fprintf(nmID,'(%d %d %d %d %d)', chans(i), shank(i), bank(i), refElec, elecInd(i) );
                    end
                    fprintf(nmID, '\n');
                
                    fclose(nmID);
                
                end
            end
        end

        % Button pushed function: SaveSelectedChsButton
        function SaveSelectedChsButtonPushed(app, event)
            values = app.SelChannels_num;
            assignin('base','data_selchs',values);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Probe View')
            xlabel(app.UIAxes, 'X (Columns)')
            ylabel(app.UIAxes, 'Y (Rows)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ShankListBoxLabel = uilabel(app.UIFigure);
            app.ShankListBoxLabel.Text = 'Shank #';
            app.ShankListBox = uilistbox(app.UIFigure);
            app.ShankListBox.Items = {'Shank 1', 'Shank 2', 'Shank 3', 'Shank 4', 'All Shanks (Just for Visualization)'};
            app.ShankListBox.ValueChangedFcn = createCallbackFcn(app, @ShankListBoxValueChanged, true);
            app.ShankListBox.Value = 'Shank 1';
            app.BankListBoxLabel = uilabel(app.UIFigure);
            app.BankListBoxLabel.Text = 'Bank #';
            app.BankListBox = uilistbox(app.UIFigure);
            app.BankListBox.Items = {'Bank 0 (bottom)', 'Bank 1', 'Bank 2', 'Bank 3 (top)', 'All Banks (Just for Visualization)'};
            app.BankListBox.ValueChangedFcn = createCallbackFcn(app, @BankListBoxValueChanged, true);
            app.BankListBox.Value = 'Bank 0 (bottom)';
            app.Sh1TotalChsEditFieldLabel = uilabel(app.UIFigure);
            app.Sh1TotalChsEditFieldLabel.Text = 'Sh1 Total Chs';
            app.Sh1TotalChsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Sh1TotalChsEditField.ValueChangedFcn = createCallbackFcn(app, @Sh1TotalChsEditFieldValueChanged, true);
            app.Sh2TotalChsEditFieldLabel = uilabel(app.UIFigure);
            app.Sh2TotalChsEditFieldLabel.Text = 'Sh2 Total Chs';
            app.Sh2TotalChsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Sh2TotalChsEditField.ValueChangedFcn = createCallbackFcn(app, @Sh2TotalChsEditFieldValueChanged, true);
            app.Sh3TotalChsEditFieldLabel = uilabel(app.UIFigure);
            app.Sh3TotalChsEditFieldLabel.Text = 'Sh3 Total Chs';
            app.Sh3TotalChsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Sh3TotalChsEditField.ValueChangedFcn = createCallbackFcn(app, @Sh3TotalChsEditFieldValueChanged, true);
            app.Sh4TotalChsEditFieldLabel = uilabel(app.UIFigure);
            app.Sh4TotalChsEditFieldLabel.Text = 'Sh4 Total Chs';
            app.Sh4TotalChsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Sh4TotalChsEditField.ValueChangedFcn = createCallbackFcn(app, @Sh4TotalChsEditFieldValueChanged, true);
            app.NP2FourShankProbeImroChannelMapGeneratorGUILabel = uilabel(app.UIFigure);
            app.NP2FourShankProbeImroChannelMapGeneratorGUILabel.Text = 'NP2 (Four Shank) Probe Imro Channel Map Generator GUI';
            app.LoadProbeViewButton = uibutton(app.UIFigure, 'state');
            app.LoadProbeViewButton.ValueChangedFcn = createCallbackFcn(app, @LoadProbeViewButtonValueChanged, true);
            app.LoadProbeViewButton.Text = 'Load Probe View';
            app.CreateImroButton = uibutton(app.UIFigure, 'state');
            app.CreateImroButton.ValueChangedFcn = createCallbackFcn(app, @CreateImroButtonValueChanged, true);
            app.CreateImroButton.Text = 'Create Imro';
            app.SelectChannelsButton = uibutton(app.UIFigure, 'state');
            app.SelectChannelsButton.ValueChangedFcn = createCallbackFcn(app, @SelectChannelsButtonValueChanged, true);
            app.SelectChannelsButton.Text = 'Select Channels';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Editable = 'off';
            app.ChannelsLeftLabel = uilabel(app.UIFigure);
            app.ChannelsLeftLabel.Text = 'Channels Left';
            app.SelectedChsTextAreaLabel = uilabel(app.UIFigure);
            app.SelectedChsTextAreaLabel.Text = 'Selected Chs';
            app.SelectedChsTextArea = uitextarea(app.UIFigure);
            app.SelectedChsTextArea.ValueChangedFcn = createCallbackFcn(app, @SelectedChsTextAreaValueChanged, true);
            app.ImportSelectedChsButton = uibutton(app.UIFigure, 'push');
            app.ImportSelectedChsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSelectedChsButtonPushed, true);
            app.ImportSelectedChsButton.Text = 'Import Selected Chs';
            app.SaveSelectedChsButton = uibutton(app.UIFigure, 'push');
            app.SaveSelectedChsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSelectedChsButtonPushed, true);
            app.SaveSelectedChsButton.Text = 'Save Selected Chs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NP2ImroGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end